classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Button                  matlab.ui.control.Button
        Label                   matlab.ui.control.Label
        selectionDropDownLabel  matlab.ui.control.Label
        selectionDropDown       matlab.ui.control.DropDown
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.Label.Text = 'MATLAB';
        end

        % Value changed function: selectionDropDown
        function selectionDropDownValueChanged(app, event)
            value = app.selectionDropDown.Value;
            app.Label.Text = value;
            switch value
                case 'Option 1'
                    plotting_details
                case 'Option 2'
                    disp('FM sgnl')
                case 'Option 3'
                    disp('PM sgnl')
                case 'Option 4'
                    disp('msg sgnl')
                otherwise
                    disp('wrong selection')
                    
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'icons8-settings-64.png';
            app.Button.Text = {''; ''};
            app.Label = uilabel(app.UIFigure);
            app.selectionDropDownLabel = uilabel(app.UIFigure);
            app.selectionDropDownLabel.Text = 'selection';
            app.selectionDropDown = uidropdown(app.UIFigure);
            app.selectionDropDown.ValueChangedFcn = createCallbackFcn(app, @selectionDropDownValueChanged, true);
            app.selectionDropDown.Value = 'Option 2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end