classdef FM_slot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        CarrierSignalLabel  matlab.ui.control.Label
        MessageSignalLabel  matlab.ui.control.Label
        FreqSlider_2        matlab.ui.control.Slider
        FreqSlider_2Label   matlab.ui.control.Label
        FreqSlider          matlab.ui.control.Slider
        FreqSliderLabel     matlab.ui.control.Label
        PlotButton          matlab.ui.control.Button
        UIAxes3             matlab.ui.control.UIAxes
        UIAxes2             matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            Ac = 1;
            Fm = app.FreqSlider.Value;
            Fc = app.FreqSlider_2.Value;
            sampling = 20;
            Tm = 1/Fm;
            Tc = 1/Fc;
            t=0:Tc/sampling:6*Tm;
            msg_sgnl = cos(2*pi*Fm*t)
            car_sgnl = Ac*cos(2*pi*Fc*t)
            plot(app.UIAxes2,msg_sgnl)
            plot(app.UIAxes,car_sgnl)
            be = 1;
            fm_sgnl = Ac*cos(2*pi*Fc*t + be*sin(2*pi*Fm*t))
            plot(app.UIAxes3,fm_sgnl)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Carrier Signal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Message Signal')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'FM- Signal')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.FreqSliderLabel = uilabel(app.UIFigure);
            app.FreqSliderLabel.Text = 'Freq';
            app.FreqSlider = uislider(app.UIFigure);
            app.FreqSlider.Limits = [200 600];
            app.FreqSlider.Value = 200;
            app.FreqSlider_2Label = uilabel(app.UIFigure);
            app.FreqSlider_2Label.Text = 'Freq';
            app.FreqSlider_2 = uislider(app.UIFigure);
            app.FreqSlider_2.Limits = [450 1800];
            app.FreqSlider_2.Value = 450;
            app.MessageSignalLabel = uilabel(app.UIFigure);
            app.MessageSignalLabel.Text = 'Message Signal';
            app.CarrierSignalLabel = uilabel(app.UIFigure);
            app.CarrierSignalLabel.Text = 'Carrier Signal';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FM_slot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end